classdef apka < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        calculateSkeleton           matlab.ui.control.CheckBox
        CurrentImageNumberLabel     matlab.ui.control.Label
        NextButton                  matlab.ui.control.Button
        PreviousButton              matlab.ui.control.Button
        AddingskeletonsSwitch       matlab.ui.control.Switch
        AddingskeletonsSwitchLabel  matlab.ui.control.Label
        CalculateButton             matlab.ui.control.Button
        CurrentPathLabel            matlab.ui.control.Label
        SegmentButtonGroup          matlab.ui.container.ButtonGroup
        SmallButton                 matlab.ui.control.RadioButton
        MediumButton                matlab.ui.control.RadioButton
        BigButton                   matlab.ui.control.RadioButton
        currentPathLabel            matlab.ui.control.Label
        changeDirButton             matlab.ui.control.Button
        SkeletonAxes                matlab.ui.control.UIAxes
        ImageAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        currentPath = 'Data'; % Description
        currentImageNumber = 1;
        half = 1;
        nOfImgs = 1;
        imgs = [];
        imgSize = [];
        skeletons = [];
        isAdded = [];
        radiosSetted = [];
        addingSkeletons = "Yes";
        colors = uint8([
            [255, 255, 255];
            [255, 0, 0];
            [0, 255, 0];
            [255, 255, 0];
            [255, 0, 128];
        ]);
        lastColorUsed = 1;
    end
    
    methods (Access = private)
        
        function displayImage(app)
            img = app.imgs(:,:,:,app.currentImageNumber);
            imshow(img, 'Parent', app.ImageAxes);
            app.displaySkeleton();
            app.CurrentImageNumberLabel.Text = app.currentImageNumber+ "/"+ app.nOfImgs;
            app.getData();
        end

        function loadImages(app)
            files = dicomreadVolume(app.currentPath);
            app.imgs = adjust_matrix(files);
            [imgRows,imgCols,~,arrSize] = size(files);
            app.imgSize = [imgRows, imgCols,arrSize];
            app.skeletons = zeros(imgRows,imgCols,3,arrSize);
            app.nOfImgs = arrSize;
            app.setDatas();
            app.displayImage();
            app.currentImageNumber = 1;
            app.setDatas();
        end

        function setDatas(app)
            app.isAdded = false(1, app.nOfImgs);
            app.radiosSetted = strings(1, app.nOfImgs);
            app.half = int8(app.nOfImgs/2);
            index = app.half-2;
            for i = 1:5
                app.isAdded(index+i) = true;
                if(i==1 || i==5)
                    app.radiosSetted(index+i) = "small";
                elseif (index+i==app.half)
                    app.radiosSetted(index+i) = "big";
                else
                    app.radiosSetted(index+i) = "medium";
                end
                
            end
        end

        function getData(app)
            app.calculateSkeleton.Value = app.isAdded(app.currentImageNumber);
            if ~app.isAdded(app.currentImageNumber)
                app.SegmentButtonGroup.Enable = "off";
                return;
            end
            app.SegmentButtonGroup.Enable = "on";
            switch app.radiosSetted(app.currentImageNumber)
                case "small"
                    app.SmallButton.Value = 1;
                case "medium"
                    app.MediumButton.Value = 1;
                case "big"
                    app.BigButton.Value = 1;
            end
        end

        function displaySkeleton(app)
            img = app.skeletons(:,:,:,app.currentImageNumber);
            imshow(img, 'Parent', app.SkeletonAxes);
        end

        function mask = segmentImage(app, img)
            img = imadjust(img);
        
            % Binarization
            BW = img > -19500;
            
            % Open mask with disk
            radius = 5;
            decomposition = 0;
            se = strel('disk', radius, decomposition);
            BW = imopen(BW, se);
            
            % Invert mask
            BW = imcomplement(BW);
            
            % Clear borders
            BW = imclearborder(BW);
            
            % Erode mask with line
            length = 5.000000;
            angle = 145.000000;
            se = strel('line', length, angle);
            BW = imerode(BW, se);
            
            % Erode mask with diamond
            radius = 1;
            se = strel('diamond', radius);
            BW = imerode(BW, se);
            
            % Dilate mask with diamond
            radius = 1;
            se = strel('diamond', radius);
            mask = int16(imdilate(BW, se));
            
        end

        function [BW,maskedImage] = segmentImagev3(app, X)
            % Adjust data to span data range.
            X = imadjust(X);
            
            % Threshold image - adaptive threshold
            BW = imbinarize(X, 'adaptive', 'Sensitivity', 0.000000, 'ForegroundPolarity', 'dark');
            
            % Open mask with diamond
            radius = 2;
            se = strel('diamond', radius);
            BW = imopen(BW, se);
            
            % Invert mask
            BW = imcomplement(BW);
            
            % Clear borders
            BW = imclearborder(BW);
            
            % Erode mask with diamond
            radius = 2;
            se = strel('diamond', radius);
            BW = double(imerode(BW, se));
            
            % Create masked image.
            maskedImage = X;
            maskedImage(~BW) = 0;
        end

        function [BW,maskedImage] = segmentImagev8(app, X)
            % Adjust data to span data range.
            X = imadjust(X);
            
            % Threshold image - manual threshold
            BW = X > -19275;
            
            % Open mask with square
            width = 6;
            se = strel('square', width);
            BW = imopen(BW, se);
            
            % Invert mask
            BW = imcomplement(BW);
            
            % Clear borders
            BW = imclearborder(BW);
            
            % Erode mask with square
            width = 2;
            se = strel('square', width);
            BW = imerode(BW, se);
            
            % Close mask with diamond
            radius = 3;
            se = strel('diamond', radius);
            BW = imclose(BW, se);
            
            % Close mask with diamond
            radius = 4;
            se = strel('diamond', radius);
            BW = imclose(BW, se);
            
            % Erode mask with disk
            radius = 1;
            decomposition = 0;
            se = strel('disk', radius, decomposition);
            BW = imerode(BW, se);
            
            % Dilate mask with diamond
            radius = 1;
            se = strel('diamond', radius);
            BW = double(imdilate(BW, se));
            
            % Create masked image.
            maskedImage = X;
            maskedImage(~BW) = 0;
        end

        function result = addSkeletons(app, old, new)
            [rows, cols, ~] = size(old);
            result = old;
            for i = 1:rows
                for j = 1:cols
                    if(new(i,j,1)~=0 || new(i,j,2)~=0 || new(i,j,3)~=0)
                        result(i,j,:) = new(i,j,:);
                    else
                        result(i,j,:) = result(i,j,:)+new(i,j,:);
                    end
                end
            end
        end

        function mask = setGlobalMask(app, mask)
            [xStart, xEnd, yStart, yEnd] = app.getBundingBox(mask);
            mask = zeros(size(mask));
            mask(xStart:xEnd, yStart:yEnd) = 1;
        end

        function [xStart, xEnd, yStart, yEnd] = getBundingBox(app, mask)
            props = regionprops(mask).BoundingBox;
            props = int16(props);
            xStart = props(1);
            xEnd = xStart + props(3);
            yStart = props(2);
            yEnd = yStart + props(4);
        end

        function colored = conv2rgb(app, img, color)
            [r, c] = size(img);
            colored = double(zeros(r, c, 3));
            for i = 1:r
                for j = 1:c
                    if img(i, j)
                        colored(i, j, 1) = color(1);
                        colored(i, j, 2) = color(2);
                        colored(i, j, 3) = color(3);
                    end
                end
            end
        end

        function matrix = adjust_matrix(app, matrix4d)
            seriesSize = size(matrix4d);
            for i = 1:seriesSize(4)
                img = imadjust(matrix4d(:,:,1,i));
                matrix4d(:,:,1,i) = img;
            end
            matrix = matrix4d;
        end
        
        function [temp, inside] = getStartingIndex(app, indexArrays)
            temp = 1;
            inside = 1;
            for i = 2:length(indexArrays)
                if abs(app.half - indexArrays(i)) <= abs(app.half - temp)
                    temp = indexArrays(i);
                    inside = i;
                end
            end
        end
        
        function results = makeMasks(app, index, globalMask)
            method = app.radiosSetted(index);
            img = app.imgs(:,:,:,index);
            switch method
                case "small"
                    mask = app.segmentImagev8(img);
                case "medium"
                    mask = app.segmentImagev3(img);
                case "big"
                    mask = app.segmentImage(img);
                otherwise
                    mask = zeros(app.imgSize(1), app.imgSize(2));
            end
            if ~isempty(globalMask)
                mask = mask.*globalMask;
            end
            results = mask;
        end

        function result = makeSkeleton(app, mask)
            skeleton = bwmorph(mask,'skel',Inf);
            result = skeleton;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.loadImages();
            app.CurrentPathLabel.Text = app.currentPath;
        end

        % Button pushed function: changeDirButton
        function changeDirButtonPushed(app, event)
            app.currentPath = uigetdir(app.currentPath);
            app.CurrentPathLabel.Text = app.currentPath;
            app.loadImages();
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            if app.currentImageNumber < app.nOfImgs
                app.currentImageNumber = app.currentImageNumber+1;
                app.displayImage();
            end
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            if app.currentImageNumber > 1
                app.currentImageNumber = app.currentImageNumber-1;
                app.displayImage();
            end
        end

        % Value changed function: calculateSkeleton
        function calculateSkeletonValueChanged(app, event)
            value = app.calculateSkeleton.Value;
            enable = "off";
            if value
                enable = "on";
            end
            app.isAdded(app.currentImageNumber) = value;
            app.SegmentButtonGroup.Enable = enable;
        end

        % Selection changed function: SegmentButtonGroup
        function SegmentButtonGroupSelectionChanged(app, event)
            selectedButton = app.SegmentButtonGroup.SelectedObject;
            app.radiosSetted(app.currentImageNumber) = selectedButton.Value;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            % calculate skeletons
            app.skeletons = zeros(app.imgSize(1),app.imgSize(2),3,app.nOfImgs);
            tempSkeletons = zeros(app.imgSize(1),app.imgSize(2),app.nOfImgs);
            toSkeletons = [];
            for i = 1:app.nOfImgs
                if app.isAdded(i)
                    toSkeletons(end+1) = i;
                end
            end
            if isempty(toSkeletons)
                return
            end
            [startIndex, insideIndex] = app.getStartingIndex(toSkeletons);
            forwardMask = [];
            startMask = [];
            %first go forward
            for i = insideIndex:length(toSkeletons)
                index = toSkeletons(i);
                mask = app.makeMasks(index, forwardMask);
                tempSkeletons(:,:,index) = app.makeSkeleton(mask);
                forwardMask = app.setGlobalMask(mask);
                if index == startIndex
                    startMask = forwardMask;
                end
            end
            backMask = startMask;
            if length(toSkeletons) <= 1
                return;
            end
            for i = insideIndex-1:-1:1
                index = toSkeletons(i);
                mask = app.makeMasks(index, backMask);
                tempSkeletons(:,:,index) = app.makeSkeleton(mask);
                backMask = app.setGlobalMask(mask);
            end

            if app.addingSkeletons=="Yes"
                app.lastColorUsed = 1;
                skeleton = app.conv2rgb(tempSkeletons(:,:,toSkeletons(1)), app.colors(app.lastColorUsed,:));
                app.lastColorUsed = app.lastColorUsed+1;
                app.skeletons(:,:,:,toSkeletons(1)) = skeleton;
                for i = 2:length(toSkeletons)
                    index = toSkeletons(i);
                    skeleton = app.addSkeletons(app.skeletons(:,:,:,toSkeletons(i-1)), app.conv2rgb(tempSkeletons(:,:,index), app.colors(app.lastColorUsed, :)));
                     if app.lastColorUsed >= length(app.colors)
                        app.lastColorUsed = 1;
                    else
                        app.lastColorUsed = app.lastColorUsed+1;
                     end
                     app.skeletons(:,:,:,index) = skeleton;
                end
            else
                for i = toSkeletons
                    skeleton = app.conv2rgb(tempSkeletons(:,:,i), app.colors(1,:));
                    app.skeletons(:,:,:,i) = skeleton;
                end
            end
            app.currentImageNumber = toSkeletons(1);
            app.displayImage();
        end

        % Value changed function: AddingskeletonsSwitch
        function AddingskeletonsSwitchValueChanged(app, event)
            value = app.AddingskeletonsSwitch.Value;
            app.addingSkeletons = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ImageAxes = uiaxes(app.GridLayout);
            title(app.ImageAxes, 'Image')
            app.ImageAxes.XTick = [];
            app.ImageAxes.YTick = [];
            app.ImageAxes.ZTick = [];
            app.SkeletonAxes = uiaxes(app.GridLayout);
            title(app.SkeletonAxes, 'Skeleton')
            app.SkeletonAxes.XTick = [];
            app.SkeletonAxes.YTick = [];
            app.SkeletonAxes.ZTick = [];
            app.changeDirButton = uibutton(app.GridLayout, 'push');
            app.changeDirButton.ButtonPushedFcn = createCallbackFcn(app, @changeDirButtonPushed, true);
            app.changeDirButton.Text = 'Change Directory';
            app.currentPathLabel = uilabel(app.GridLayout);
            app.currentPathLabel.Text = '';
            app.SegmentButtonGroup = uibuttongroup(app.GridLayout);
            app.SegmentButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SegmentButtonGroupSelectionChanged, true);
            app.SegmentButtonGroup.Title = 'Segment';
            app.BigButton = uiradiobutton(app.SegmentButtonGroup);
            app.BigButton.Text = 'Big';
            app.BigButton.Value = true;
            app.MediumButton = uiradiobutton(app.SegmentButtonGroup);
            app.MediumButton.Text = 'Medium';
            app.SmallButton = uiradiobutton(app.SegmentButtonGroup);
            app.SmallButton.Text = 'Small';
            app.CurrentPathLabel = uilabel(app.GridLayout);
            app.CurrentPathLabel.Text = '';
            app.CalculateButton = uibutton(app.GridLayout, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate!';
            app.AddingskeletonsSwitchLabel = uilabel(app.GridLayout);
            app.AddingskeletonsSwitchLabel.Text = 'Adding skeletons';
            app.AddingskeletonsSwitch = uiswitch(app.GridLayout, 'slider');
            app.AddingskeletonsSwitch.Items = {'No', 'Yes'};
            app.AddingskeletonsSwitch.ValueChangedFcn = createCallbackFcn(app, @AddingskeletonsSwitchValueChanged, true);
            app.AddingskeletonsSwitch.Value = 'Yes';
            app.PreviousButton = uibutton(app.GridLayout, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = '<< Previous';
            app.NextButton = uibutton(app.GridLayout, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next >>';
            app.CurrentImageNumberLabel = uilabel(app.GridLayout);
            app.CurrentImageNumberLabel.Text = '';
            app.calculateSkeleton = uicheckbox(app.GridLayout);
            app.calculateSkeleton.ValueChangedFcn = createCallbackFcn(app, @calculateSkeletonValueChanged, true);
            app.calculateSkeleton.Text = 'Include to skeletization';
            app.calculateSkeleton.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = apka

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end